classdef traitement < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        TabGroup                  matlab.ui.container.TabGroup
        CHOISIRIMAGETab           matlab.ui.container.Tab
        UIAxes                    matlab.ui.control.UIAxes
        ChargerimageButton        matlab.ui.control.Button
        LIGNESEditFieldLabel      matlab.ui.control.Label
        LIGNESEditField           matlab.ui.control.NumericEditField
        COLONNESEditFieldLabel    matlab.ui.control.Label
        COLONNESEditField         matlab.ui.control.NumericEditField
        TailledelimageLabel       matlab.ui.control.Label
        imagepathEditFieldLabel   matlab.ui.control.Label
        imagepathEditField        matlab.ui.control.EditField
        SouspartieTab             matlab.ui.container.Tab
        UIAxes2                   matlab.ui.control.UIAxes
        debutEditFieldLabel       matlab.ui.control.Label
        debutLEditField           matlab.ui.control.NumericEditField
        finEditFieldLabel         matlab.ui.control.Label
        finLEditField             matlab.ui.control.NumericEditField
        LignesLabel               matlab.ui.control.Label
        ColonnesLabel             matlab.ui.control.Label
        debutEditField_2Label     matlab.ui.control.Label
        debutCEditField_2         matlab.ui.control.NumericEditField
        finEditField_2Label       matlab.ui.control.Label
        finCEditField_2           matlab.ui.control.NumericEditField
        SouspartieButton          matlab.ui.control.Button
        RedimensionnerTab         matlab.ui.container.Tab
        lerapportderedimensionnerSpinnerLabel  matlab.ui.control.Label
        lerapportderedimensionnerSpinner  matlab.ui.control.Spinner
        RedimensionnerButton      matlab.ui.control.Button
        exemplepouragrandirlimageonchoisitunrapportsuperieur1Label  matlab.ui.control.Label
        LesplansTab               matlab.ui.container.Tab
        UIAxes7                   matlab.ui.control.UIAxes
        UIAxes8                   matlab.ui.control.UIAxes
        UIAxes9                   matlab.ui.control.UIAxes
        AfficherRougeButton       matlab.ui.control.Button
        AfficherVertButton        matlab.ui.control.Button
        AfficherBleuButton        matlab.ui.control.Button
        EspaceCouleurTab          matlab.ui.container.Tab
        UIAxes10                  matlab.ui.control.UIAxes
        UIAxes11                  matlab.ui.control.UIAxes
        UIAxes12                  matlab.ui.control.UIAxes
        EspaceHSVButton           matlab.ui.control.Button
        EspaceYCbCrButton         matlab.ui.control.Button
        EspaceNTSCButton          matlab.ui.control.Button
        HistogrammeTab            matlab.ui.container.Tab
        UIAxes13                  matlab.ui.control.UIAxes
        UIAxes14                  matlab.ui.control.UIAxes
        gnreButton                matlab.ui.control.Button
        QuantificationTab         matlab.ui.container.Tab
        UIAxes15                  matlab.ui.control.UIAxes
        UIAxes16                  matlab.ui.control.UIAxes
        ChoisirlenombredeniveaudegrisEditFieldLabel  matlab.ui.control.Label
        ChoisirlenombredeniveaudegrisEditField  matlab.ui.control.NumericEditField
        gnreButton_2              matlab.ui.control.Button
        ScanningTab               matlab.ui.container.Tab
        UIAxes17                  matlab.ui.control.UIAxes
        ScanButton                matlab.ui.control.Button
        ChoisirpixelSpinnerLabel  matlab.ui.control.Label
        ChoisirpixelSpinner       matlab.ui.control.Spinner
        TraitementdimageLabel     matlab.ui.control.Label
    end

    methods (Access = private)

        % Button pushed function: ChargerimageButton
        function ChargerimageButtonPushed(app, event)
            global I;
            [filename, filepath] = uigetfile({'*.jpg'}, "open file");
            fullpathname = strcat(filepath, filename);
            I = imread(fullpathname);
            l = size(I, 1);
            c = size(I, 2);
            app.LIGNESEditField.Value = l;
            app.COLONNESEditField.Value = c;
            app.imagepathEditField.Value = fullpathname;
            imshow(I, 'parent', app.UIAxes);
        end

        % Button pushed function: SouspartieButton
        function SouspartieButtonPushed(app, event)
            img = imread(app.imagepathEditField.Value);
            dl = app.debutLEditField.Value;
            fl = app.finLEditField.Value;
            dc = app.debutCEditField_2.Value;
            fc = app.finCEditField_2.Value;
            bloc = img(dl:fl,dc:fc);
            imshow(bloc, 'parent', app.UIAxes2);
        end

        % Value changing function: imagepathEditField
        function imagepathEditFieldValueChanging(app, event)
            changingValue = event.Value;
            
        end

        % Button pushed function: RedimensionnerButton
        function RedimensionnerButtonPushed(app, event)
            img = imread(app.imagepathEditField.Value);
            img = imresize(img, app.lerapportderedimensionnerSpinner.Value);
            imshow(img);
        end

        % Button pushed function: AfficherRougeButton
        function AfficherRougeButtonPushed(app, event)
            red = imread(app.imagepathEditField.Value);
            red(:,:,[2 3]) = 0;
            imshow(red, 'parent', app.UIAxes7);
        end

        % Button pushed function: AfficherVertButton
        function AfficherVertButtonPushed(app, event)
            green = imread(app.imagepathEditField.Value);
            green(:,:,[1 3]) = 0;
            imshow(green, 'parent', app.UIAxes8);
        end

        % Button pushed function: AfficherBleuButton
        function AfficherBleuButtonPushed(app, event)
            blue = imread(app.imagepathEditField.Value);
            blue(:,:,[1 2]) = 0;
            imshow(blue, 'parent', app.UIAxes9);
        end

        % Button pushed function: EspaceHSVButton
        function EspaceHSVButtonPushed(app, event)
            img = imread(app.imagepathEditField.Value);
            img = rgb2hsv(img);
            imshow(img, 'parent', app.UIAxes10);
        end

        % Button pushed function: EspaceYCbCrButton
        function EspaceYCbCrButtonPushed(app, event)
            img = imread(app.imagepathEditField.Value);
            img = rgb2ycbcr(img);
            imshow(img, 'parent', app.UIAxes11);
        end

        % Button pushed function: EspaceNTSCButton
        function EspaceNTSCButtonPushed(app, event)
             img = imread(app.imagepathEditField.Value);
             img = rgb2ntsc(img);
             imshow(img, 'parent', app.UIAxes12);
        end

        % Button pushed function: gnreButton
        function gnreButtonPushed(app, event)
             img = imread(app.imagepathEditField.Value);
             img = rgb2gray(img);
             imshow(img, 'parent', app.UIAxes13);
             [x,y] = size(img);
             hist = zeros(255);
             for i = 1:x
                for j = 1:y
                    hist(double(img(i,j)))=hist(double(img(i,j)))+1;
                end
             end
             plot(hist, 'parent', app.UIAxes14);
        end

        % Button pushed function: gnreButton_2
        function gnreButton_2Pushed(app, event)
            img = imread(app.imagepathEditField.Value);
            n=app.ChoisirlenombredeniveaudegrisEditField.Value;
            d=255/n-1; 
            J= uint8(floor(img/d));
            imshow(J, 'parent', app.UIAxes16);
            J = rgb2gray(J);
            plot(J, 'parent', app.UIAxes15);  
            
        end

        % Button pushed function: ScanButton
        function ScanButtonPushed(app, event)
            img = imread(app.imagepathEditField.Value);
            n = app.ChoisirpixelSpinner.Value;
            l = app.LIGNESEditField.Value;
            c = app.COLONNESEditField.Value;
            for i = 1:l
                for j= 1:c
                    if img(i,j) == n
                        img(i,j) = 255;
                    end
                end
            end
            imshow(img, 'parent', app.UIAxes17);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.CHOISIRIMAGETab = uitab(app.TabGroup);
            app.CHOISIRIMAGETab.Title = 'CHOISIR IMAGE';
            app.UIAxes = uiaxes(app.CHOISIRIMAGETab);
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.ChargerimageButton = uibutton(app.CHOISIRIMAGETab, 'push');
            app.ChargerimageButton.ButtonPushedFcn = createCallbackFcn(app, @ChargerimageButtonPushed, true);
            app.ChargerimageButton.Text = 'Charger image';
            app.LIGNESEditFieldLabel = uilabel(app.CHOISIRIMAGETab);
            app.LIGNESEditFieldLabel.Text = 'LIGNES';
            app.LIGNESEditField = uieditfield(app.CHOISIRIMAGETab, 'numeric');
            app.COLONNESEditFieldLabel = uilabel(app.CHOISIRIMAGETab);
            app.COLONNESEditFieldLabel.Text = 'COLONNES';
            app.COLONNESEditField = uieditfield(app.CHOISIRIMAGETab, 'numeric');
            app.TailledelimageLabel = uilabel(app.CHOISIRIMAGETab);
            app.TailledelimageLabel.Text = 'Taille de l''image';
            app.imagepathEditFieldLabel = uilabel(app.CHOISIRIMAGETab);
            app.imagepathEditFieldLabel.Text = 'image path';
            app.imagepathEditField = uieditfield(app.CHOISIRIMAGETab, 'text');
            app.imagepathEditField.ValueChangingFcn = createCallbackFcn(app, @imagepathEditFieldValueChanging, true);
            app.SouspartieTab = uitab(app.TabGroup);
            app.SouspartieTab.Title = 'Sous partie';
            app.UIAxes2 = uiaxes(app.SouspartieTab);
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            app.debutEditFieldLabel = uilabel(app.SouspartieTab);
            app.debutEditFieldLabel.Text = 'debut';
            app.debutLEditField = uieditfield(app.SouspartieTab, 'numeric');
            app.finEditFieldLabel = uilabel(app.SouspartieTab);
            app.finEditFieldLabel.Text = 'fin';
            app.finLEditField = uieditfield(app.SouspartieTab, 'numeric');
            app.LignesLabel = uilabel(app.SouspartieTab);
            app.LignesLabel.Text = 'Lignes';
            app.ColonnesLabel = uilabel(app.SouspartieTab);
            app.ColonnesLabel.Text = 'Colonnes';
            app.debutEditField_2Label = uilabel(app.SouspartieTab);
            app.debutEditField_2Label.Text = 'debut';
            app.debutCEditField_2 = uieditfield(app.SouspartieTab, 'numeric');
            app.finEditField_2Label = uilabel(app.SouspartieTab);
            app.finEditField_2Label.Text = 'fin';
            app.finCEditField_2 = uieditfield(app.SouspartieTab, 'numeric');
            app.SouspartieButton = uibutton(app.SouspartieTab, 'push');
            app.SouspartieButton.ButtonPushedFcn = createCallbackFcn(app, @SouspartieButtonPushed, true);
            app.SouspartieButton.Text = 'Sous partie';
            app.RedimensionnerTab = uitab(app.TabGroup);
            app.RedimensionnerTab.Title = 'Redimensionner';
            app.lerapportderedimensionnerSpinnerLabel = uilabel(app.RedimensionnerTab);
            app.lerapportderedimensionnerSpinnerLabel.Text = 'le rapport de redimensionner';
            app.lerapportderedimensionnerSpinner = uispinner(app.RedimensionnerTab);
            app.lerapportderedimensionnerSpinner.Step = 0.1;
            app.lerapportderedimensionnerSpinner.Limits = [0 Inf];
            app.RedimensionnerButton = uibutton(app.RedimensionnerTab, 'push');
            app.RedimensionnerButton.ButtonPushedFcn = createCallbackFcn(app, @RedimensionnerButtonPushed, true);
            app.RedimensionnerButton.Text = 'Redimensionner';
            app.exemplepouragrandirlimageonchoisitunrapportsuperieur1Label = uilabel(app.RedimensionnerTab);
            app.exemplepouragrandirlimageonchoisitunrapportsuperieur1Label.Text = 'exemple : pour agrandir l''image on choisit un rapport superieur 1 ';
            app.LesplansTab = uitab(app.TabGroup);
            app.LesplansTab.Title = 'Les plans';
            app.UIAxes7 = uiaxes(app.LesplansTab);
            title(app.UIAxes7, 'Rouge')
            xlabel(app.UIAxes7, 'X')
            ylabel(app.UIAxes7, 'Y')
            app.UIAxes8 = uiaxes(app.LesplansTab);
            title(app.UIAxes8, 'Vert')
            xlabel(app.UIAxes8, 'X')
            ylabel(app.UIAxes8, 'Y')
            app.UIAxes9 = uiaxes(app.LesplansTab);
            title(app.UIAxes9, 'Bleu')
            xlabel(app.UIAxes9, 'X')
            ylabel(app.UIAxes9, 'Y')
            app.AfficherRougeButton = uibutton(app.LesplansTab, 'push');
            app.AfficherRougeButton.ButtonPushedFcn = createCallbackFcn(app, @AfficherRougeButtonPushed, true);
            app.AfficherRougeButton.Text = 'Afficher Rouge';
            app.AfficherVertButton = uibutton(app.LesplansTab, 'push');
            app.AfficherVertButton.ButtonPushedFcn = createCallbackFcn(app, @AfficherVertButtonPushed, true);
            app.AfficherVertButton.Text = 'Afficher Vert';
            app.AfficherBleuButton = uibutton(app.LesplansTab, 'push');
            app.AfficherBleuButton.ButtonPushedFcn = createCallbackFcn(app, @AfficherBleuButtonPushed, true);
            app.AfficherBleuButton.Text = 'Afficher Bleu';
            app.EspaceCouleurTab = uitab(app.TabGroup);
            app.EspaceCouleurTab.Title = 'Espace Couleur';
            app.UIAxes10 = uiaxes(app.EspaceCouleurTab);
            title(app.UIAxes10, 'HSV')
            xlabel(app.UIAxes10, 'X')
            ylabel(app.UIAxes10, 'Y')
            app.UIAxes11 = uiaxes(app.EspaceCouleurTab);
            title(app.UIAxes11, 'YCbCr')
            xlabel(app.UIAxes11, 'X')
            ylabel(app.UIAxes11, 'Y')
            app.UIAxes12 = uiaxes(app.EspaceCouleurTab);
            title(app.UIAxes12, 'NTSC')
            xlabel(app.UIAxes12, 'X')
            ylabel(app.UIAxes12, 'Y')
            app.EspaceHSVButton = uibutton(app.EspaceCouleurTab, 'push');
            app.EspaceHSVButton.ButtonPushedFcn = createCallbackFcn(app, @EspaceHSVButtonPushed, true);
            app.EspaceHSVButton.Text = 'Espace HSV';
            app.EspaceYCbCrButton = uibutton(app.EspaceCouleurTab, 'push');
            app.EspaceYCbCrButton.ButtonPushedFcn = createCallbackFcn(app, @EspaceYCbCrButtonPushed, true);
            app.EspaceYCbCrButton.Text = 'Espace YCbCr';
            app.EspaceNTSCButton = uibutton(app.EspaceCouleurTab, 'push');
            app.EspaceNTSCButton.ButtonPushedFcn = createCallbackFcn(app, @EspaceNTSCButtonPushed, true);
            app.EspaceNTSCButton.Text = 'Espace NTSC';
            app.HistogrammeTab = uitab(app.TabGroup);
            app.HistogrammeTab.Title = 'Histogramme';
            app.UIAxes13 = uiaxes(app.HistogrammeTab);
            title(app.UIAxes13, 'Image au niveau de gris')
            xlabel(app.UIAxes13, 'X')
            ylabel(app.UIAxes13, 'Y')
            app.UIAxes14 = uiaxes(app.HistogrammeTab);
            title(app.UIAxes14, 'Histogramme')
            xlabel(app.UIAxes14, 'X')
            ylabel(app.UIAxes14, 'Y')
            app.gnreButton = uibutton(app.HistogrammeTab, 'push');
            app.gnreButton.ButtonPushedFcn = createCallbackFcn(app, @gnreButtonPushed, true);
            app.gnreButton.Text = 'génére';
            app.QuantificationTab = uitab(app.TabGroup);
            app.QuantificationTab.Title = 'Quantification';
            app.UIAxes15 = uiaxes(app.QuantificationTab);
            title(app.UIAxes15, 'Image')
            xlabel(app.UIAxes15, 'X')
            ylabel(app.UIAxes15, 'Y')
            app.UIAxes16 = uiaxes(app.QuantificationTab);
            title(app.UIAxes16, 'Histogramme')
            xlabel(app.UIAxes16, 'X')
            ylabel(app.UIAxes16, 'Y')
            app.ChoisirlenombredeniveaudegrisEditFieldLabel = uilabel(app.QuantificationTab);
            app.ChoisirlenombredeniveaudegrisEditFieldLabel.Text = 'Choisir le nombre de niveau de gris';
            app.ChoisirlenombredeniveaudegrisEditField = uieditfield(app.QuantificationTab, 'numeric');
            app.gnreButton_2 = uibutton(app.QuantificationTab, 'push');
            app.gnreButton_2.ButtonPushedFcn = createCallbackFcn(app, @gnreButton_2Pushed, true);
            app.gnreButton_2.Text = 'génére';
            app.ScanningTab = uitab(app.TabGroup);
            app.ScanningTab.Title = 'Scanning';
            app.UIAxes17 = uiaxes(app.ScanningTab);
            xlabel(app.UIAxes17, 'X')
            ylabel(app.UIAxes17, 'Y')
            app.ScanButton = uibutton(app.ScanningTab, 'push');
            app.ScanButton.ButtonPushedFcn = createCallbackFcn(app, @ScanButtonPushed, true);
            app.ScanButton.Text = 'Scan';
            app.ChoisirpixelSpinnerLabel = uilabel(app.ScanningTab);
            app.ChoisirpixelSpinnerLabel.Text = 'Choisir pixel';
            app.ChoisirpixelSpinner = uispinner(app.ScanningTab);
            app.TraitementdimageLabel = uilabel(app.UIFigure);
            app.TraitementdimageLabel.Text = 'Traitement d''image';
        end
    end

    methods (Access = public)

        % Construct app
        function app = traitement

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end